classdef plasma_frequency_and_permittivity_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DispersionrelationTab          matlab.ui.container.Tab
        epsilon_r1Label                matlab.ui.control.Label
        epsilon1EditField              matlab.ui.control.NumericEditField
        electrondensityEditFieldLabel  matlab.ui.control.Label
        electrondensityEditField       matlab.ui.control.NumericEditField
        effectivemassEditFieldLabel    matlab.ui.control.Label
        effectivemassEditField         matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        ElectroniccontributiontopermittivittyTab  matlab.ui.container.Tab
        UIAxes2_1                      matlab.ui.control.UIAxes
        resistivityEditFieldLabel      matlab.ui.control.Label
        resistivityEditField_2         matlab.ui.control.NumericEditField
        epsilon_r1Label_2              matlab.ui.control.Label
        epsilon1EditField_2            matlab.ui.control.NumericEditField
        electrondensityEditField_2Label  matlab.ui.control.Label
        electrondensityEditField_2     matlab.ui.control.NumericEditField
        effectivemassEditField_2Label  matlab.ui.control.Label
        effectivemassEditField_2       matlab.ui.control.NumericEditField
        UIAxes2_2                      matlab.ui.control.UIAxes
        DipolarcontributiontopermittivityTab  matlab.ui.container.Tab
        epsilon_r1Label_3              matlab.ui.control.Label
        epsilon1EditField_3            matlab.ui.control.NumericEditField
        electrondensityEditField_3Label  matlab.ui.control.Label
        electrondensityEditField_3     matlab.ui.control.NumericEditField
        resistivityEditField_2Label    matlab.ui.control.Label
        resistivityEditField_3         matlab.ui.control.NumericEditField
        effectivemassEditField_3Label  matlab.ui.control.Label
        effectivemassEditField_3       matlab.ui.control.NumericEditField
        UIAxes3_1                      matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        n_e; % electron density
        c; % light velocity
        q; % electron charge 
        m_e % effective mass
        epsilon_0; % 8.854e-12
        epsilon_r1; % relative permittivity  medium 1
        epsilon_r2; % relative permittivity medium 2
        m_0; % electron mass
        rho; % resistivity
        font_size; % font size for plot
        number_of_points; % number of points for plot
        color_im; % color imaginary part of permittivity
        color_real; % color real part of permittivity
    end
    
    methods (Access = public)
        
        function [] = plot_dispersion_relation(app)
            cla(app.UIAxes);
            
            omega_p = sqrt((app.n_e*app.q^2)/(app.epsilon_0*app.epsilon_r1*app.m_0*app.m_e)); %plasma frequency (ionosphere)
            omega_sp = omega_p/sqrt(app.epsilon_r1+1); %surface plasmon frequency
            omega = logspace(0, log10(omega_p), app.number_of_points); % set omega from 1 to omega_p
            app.epsilon_r2 = 1 - omega_p^2./omega.^2; %permittivy material 2 (metal) this is a function of frequency
            beta = omega/app.c.*sqrt((app.epsilon_r1*app.epsilon_r2)./(app.epsilon_r1+app.epsilon_r2));
            index = find(omega > omega_sp, 1); %find index of omega > omega_sp
            % plot omega < omega_sp
            plot (app.UIAxes, real(beta(1:index-1)), omega(1:index-1)); %plot omega < omega_sp
            text (app.UIAxes, 0, omega(index-1), "$\omega_{sp}$", 'Interpreter', 'latex', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize', app.font_size);
            % line at omega_sp
            hold (app.UIAxes, "on");
            plot (app.UIAxes, [0 max(real(beta))],[omega_sp omega_sp]); %line at omega_sp
            % plot omega_sp < omega < omega_p
            hold (app.UIAxes, "on");
            plot (app.UIAxes, real(beta(index:end)), omega(index:end)); %plot omega_sp < omega < omega_p
            % plot omega > omega_p
            hold (app.UIAxes, "on");
            beta = logspace (0, log10(max(real(beta))), app.number_of_points); % redefining beta for omega > omega_p
            omega_plasma = sqrt(omega_p^2 + app.c^2*beta.^2); % formula for find omega values for omega > omaga_p
            plot (app.UIAxes, real(beta), omega_plasma); % plot omega > omega_p
            text (app.UIAxes, 0, omega_p, "$\omega_p$", 'Interpreter', 'latex', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize', app.font_size);
            % plot omega = c*beta curve asymptote 
            hold (app.UIAxes, "on");
            plot (app.UIAxes, real(beta), app.c*real(beta)); % plot omega = c*beta
            %text (app.UIAxes, max(real(beta)), app.c*max(real(beta)), '$\leftarrow \omega = c \cdot \beta$', 'Interpreter', 'latex','FontSize',14);            
            
            xlim (app.UIAxes, [min(real(beta)) max(real(beta))]);
            ylim (app.UIAxes, [min(omega) max(omega_plasma)]);
            xlabel (app.UIAxes, "$\beta~\left[\frac{1}{m}\right]$", 'Interpreter', 'latex', 'FontSize', app.font_size);
            ylabel (app.UIAxes, "$\omega~\left[\frac{rad}{s}\right]$", 'Interpreter', 'latex', 'FontSize', app.font_size);
            title (app.UIAxes, "Dispersion relation $\omega(\beta)$", 'Interpreter', 'latex', 'FontSize', app.font_size);
            legend (app.UIAxes, "$\beta = \frac{\omega}{c} \sqrt{\frac{\epsilon_{r1} \epsilon_{r2}}{\epsilon_{r1}+\epsilon_{r2}}}$", "$\omega = \omega_{sp}$", "Prohibited region", "$\omega^2 = \omega_p^2 + c^2 \cdot \beta^2$", "$\omega = c \cdot \beta$",'Interpreter', 'latex', 'Location', 'eastoutside', 'FontSize', app.font_size);
        end
        
        function [] = plot_electronic_contibution_to_permittivitypermittivity(app)
            omega_p = sqrt((app.n_e*app.q^2)/(app.epsilon_0*app.epsilon_r1*app.m_0*app.m_e)); %plasma frequency (ionosphere)
            omega = logspace(0, log10(10*omega_p), app.number_of_points);
            tau = app.m_0*app.m_e/(app.n_e*app.rho*(app.q^2)); % mean time between collision (in copper)
            gamma = 1/tau; % damping coefficient
            omega_0 = omega_p/2; % resonant frequency of the electron motion (taken arbitrary)
            epsilon_ie_real = app.epsilon_r1+((app.epsilon_r1*omega_p^2*(omega_0^2-omega.^2))./((omega_0^2-omega.^2).^2+omega.^2*gamma^2));
            epsilon_ie_imag = app.epsilon_r1+((app.epsilon_r1*omega_p^2*omega*gamma)./((omega_0^2-omega.^2).^2+omega.^2*gamma^2));
            
            plot (app.UIAxes2_1, omega, epsilon_ie_real, app.color_real);
            set(app.UIAxes2_1, 'XScale', 'log');
            xline(app.UIAxes2_1, omega_0);
            xlim (app.UIAxes2_1, [10^(round(log10(omega_0/10))) 10^(round(log10(omega_0*10)))]);
            xticks (app.UIAxes2_1, [10^(round(log10(omega_0/10))) 10^(round(log10(omega_0*10)))]);
            ylim (app.UIAxes2_1, [min(epsilon_ie_real) max(epsilon_ie_real)]);
            text (app.UIAxes2_1, omega_0, min(epsilon_ie_real), '$\omega_0$','Interpreter', 'latex', 'VerticalAlignment', 'top', 'HorizontalAlignment', 'center', 'FontSize', app.font_size);
            xlabel (app.UIAxes2_1, '$\log(\omega)$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            ylabel (app.UIAxes2_1, 'Re $\{\epsilon(\omega)\}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            title(app.UIAxes2_1, 'Re $\{\epsilon(\omega)\}=\varepsilon_{r0}+\frac{\varepsilon_{r0} \omega_p^2 (\omega_0^2-\omega^2)}{(\omega_0^2-\omega^2)^2+ \omega^2 \Gamma^2}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            
            
            plot (app.UIAxes2_2, omega, epsilon_ie_imag, app.color_im);
            set(app.UIAxes2_2, 'XScale', 'log');
            xline(app.UIAxes2_2, omega_0);
            xlim (app.UIAxes2_2, [10^(round(log10(omega_0/10))) 10^(round(log10(omega_0*10)))]);
            xticks (app.UIAxes2_2, [10^(round(log10(omega_0/10))) 10^(round(log10(omega_0*10)))]);
            ylim (app.UIAxes2_2, [min(epsilon_ie_imag) max(epsilon_ie_imag)]);
            text (app.UIAxes2_2, omega_0, min(epsilon_ie_imag), '$\omega_0$','Interpreter', 'latex', 'VerticalAlignment', 'top', 'HorizontalAlignment', 'center', 'FontSize', app.font_size);
            xlabel (app.UIAxes2_2, '$\log(\omega)$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            ylabel (app.UIAxes2_2, 'Im $\{\epsilon(\omega)\}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            title (app.UIAxes2_2, 'Im $\{\epsilon(\omega)\}=\frac{\varepsilon_{r0} \omega_p^2 \omega \Gamma}{(\omega_0^2 - \omega^2)^2+ \omega^2 \Gamma^2}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
        end
        
        function [] = plot_dipolar_contibution_to_permittivitypermittivity(app)
            tau = app.m_0*app.m_e/(app.n_e*app.rho*(app.q^2)); % mean time between collision (in copper)
            gamma = 1/tau; % damping coefficient
            omega = logspace(0, log10(app.number_of_points/tau), app.number_of_points);
            sigma = app.n_e * app.q^2 /(app.m_e*app.m_0*gamma);
            epsilon_dip_real = app.epsilon_r1+ sigma./(1+omega*tau);
            epsilon_dip_imag = sigma*omega*tau./(1+(omega*tau).^2); 

            plot (app.UIAxes3_1, omega, epsilon_dip_real, app.color_real);
            set(app.UIAxes3_1, 'XScale', 'log');
            xline(app.UIAxes3_1, 1/tau);
            xlim (app.UIAxes3_1, [1 10^(round(log10(1000/tau)))]);
            text (app.UIAxes3_1, 1/tau, 0, '$\omega\tau = 1$','Interpreter', 'latex', 'HorizontalAlignment', 'center','VerticalAlignment', 'top', 'FontSize', app.font_size);
            xlabel (app.UIAxes3_1, '$\log(\omega)$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            ylabel (app.UIAxes3_1, 'Re $\{\epsilon(\omega)\}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            title (app.UIAxes3_1, 'Re $\{\epsilon(\omega)\} = \varepsilon_{r0}+\frac{\sigma_0}{1+(\omega \tau)^2}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            
            plot (app.UIAxes3_2, omega, epsilon_dip_imag, app.color_im);
            set(app.UIAxes3_2, 'XScale', 'log');
            xline(app.UIAxes3_2, 1/tau);
            xlim (app.UIAxes3_2, [1 10^(round(log10(1000/tau)))]);
            text (app.UIAxes3_2, 1/tau, 0, '$\omega\tau = 1$','Interpreter', 'latex', 'HorizontalAlignment', 'center','VerticalAlignment', 'top', 'FontSize', app.font_size);
            xlabel (app.UIAxes3_2, '$\log(\omega)$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            ylabel (app.UIAxes3_2, 'Im $\{\epsilon(\omega)\}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
            title (app.UIAxes3_2, 'Im $\{\epsilon(\omega)\}=\frac{\sigma \omega \tau}{1+(\omega\tau)^2}$', 'Interpreter', 'latex', 'FontSize', app.font_size);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % costants
            app.c = 3*10^8; %light velocity
            app.q = 1.602176634*10^(-19); %electric charge
            app.m_0 = 9.1093837015*10^(-31); %electron mass
            app.epsilon_0 = 8.8541878128*10^(-12); % vacuum permittivity
            
            % plot parameters
            app.font_size = 14;
            app.number_of_points = 1000;
            app.color_im = 'r';
            app.color_real = 'b';
            
            % material parameters
            %app.n_e = 9.65*10^9; %silicon electron density
            %app.n_e = 9.65*10^9; %silicon electron density
            %app.n_e = 5.86*10^28; %conductor electron density (silver)
            %app.n_e = 5.90*10^28; %conductor electron density (gold)
            app.n_e = 8.47*10^28; %conductor electron density (copper)
            app.electrondensityEditField.Value = app.n_e;
            app.electrondensityEditField_2.Value = app.n_e;
            app.electrondensityEditField_3.Value = app.n_e;
            
            app.epsilon_r1 = 1; %permittivy material 1 (dielectric), vacuum permittivy
            app.epsilon1EditField.Value = app.epsilon_r1;
            app.epsilon1EditField_2.Value = app.epsilon_r1;
            app.epsilon1EditField_3.Value = app.epsilon_r1;
            
            app.m_e = 1;
            app.effectivemassEditField.Value = app.m_e;
            app.effectivemassEditField_2.Value = app.m_e;
            app.effectivemassEditField_3.Value = app.m_e;
            
            app.rho = 1.72*10^(-8);
            app.resistivityEditField_2.Value = app.rho;
            app.resistivityEditField_3.Value = app.rho;

            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: epsilon1EditField
        function epsilon1EditFieldValueChanged(app, event)
            app.epsilon_r1 = app.epsilon1EditField.Value;
            app.epsilon1EditField_2.Value = app.epsilon1EditField.Value;
            app.epsilon1EditField_3.Value = app.epsilon1EditField.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: electrondensityEditField
        function electrondensityEditFieldValueChanged(app, event)
            app.n_e = app.electrondensityEditField.Value;
            app.electrondensityEditField_2.Value = app.electrondensityEditField.Value;
            app.electrondensityEditField_3.Value = app.electrondensityEditField.Value;
            
            plot_dispersion_relation(app);
        end

        % Value changed function: effectivemassEditField
        function effectivemassEditFieldValueChanged(app, event)
            app.m_e = app.effectivemassEditField.Value;
            app.effectivemassEditField_2.Value = app.effectivemassEditField.Value;
            app.effectivemassEditField_3.Value = app.effectivemassEditField.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: resistivityEditField_2
        function resistivityEditField_2ValueChanged(app, event)
            app.rho = app.resistivityEditField_2.Value;
            app.resistivityEditField_3.Value = app.resistivityEditField_2.Value;
            
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: epsilon1EditField_2
        function epsilon1EditField_2ValueChanged(app, event)
            app.epsilon_r1 = app.epsilon1EditField_2.Value;
            app.epsilon1EditField.Value = app.epsilon1EditField_2.Value;
            app.epsilon1EditField_3.Value = app.epsilon1EditField_2.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: electrondensityEditField_2
        function electrondensityEditField_2ValueChanged(app, event)
            app.n_e = app.electrondensityEditField_2.Value;
            app.electrondensityEditField.Value = app.electrondensityEditField_2.Value;
            app.electrondensityEditField_3.Value = app.electrondensityEditField_2.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: effectivemassEditField_2
        function effectivemassEditField_2ValueChanged(app, event)
            app.m_e = app.effectivemassEditField_2.Value;
            app.effectivemassEditField.Value = app.effectivemassEditField_2.Value;
            app.effectivemassEditField_3.Value = app.effectivemassEditField_2.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: epsilon1EditField_3
        function epsilon1EditField_3ValueChanged(app, event)
            app.epsilon_r1 = app.epsilon1EditField_3.Value;
            app.epsilon1EditField.Value = app.epsilon1EditField_3.Value;
            app.epsilon1EditField_2.Value = app.epsilon1EditField_3.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: electrondensityEditField_3
        function electrondensityEditField_3ValueChanged(app, event)
            app.n_e = app.electrondensityEditField_3.Value;
            app.electrondensityEditField.Value = app.electrondensityEditField_3.Value;
            app.electrondensityEditField_2.Value = app.electrondensityEditField_3.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: resistivityEditField_3
        function resistivityEditField_3ValueChanged(app, event)
            app.rho = app.resistivityEditField_3.Value;
            app.resistivityEditField_2.Value = app.resistivityEditField_3.Value;
            
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end

        % Value changed function: effectivemassEditField_3
        function effectivemassEditField_3ValueChanged(app, event)
            app.m_e = app.effectivemassEditField_3.Value;
            app.effectivemassEditField.Value = app.effectivemassEditField_3.Value;
            app.effectivemassEditField_2.Value = app.effectivemassEditField_3.Value;
            
            plot_dispersion_relation(app);
            plot_electronic_contibution_to_permittivitypermittivity(app);
            plot_dipolar_contibution_to_permittivitypermittivity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DispersionrelationTab = uitab(app.TabGroup);
            app.DispersionrelationTab.Title = 'Dispersion relation';
            app.epsilon_r1Label = uilabel(app.DispersionrelationTab);
            app.epsilon_r1Label.Text = 'epsilon_r1';
            app.epsilon1EditField = uieditfield(app.DispersionrelationTab, 'numeric');
            app.epsilon1EditField.ValueChangedFcn = createCallbackFcn(app, @epsilon1EditFieldValueChanged, true);
            app.epsilon1EditField.Value = 1;
            app.electrondensityEditFieldLabel = uilabel(app.DispersionrelationTab);
            app.electrondensityEditFieldLabel.Text = 'electron density';
            app.electrondensityEditField = uieditfield(app.DispersionrelationTab, 'numeric');
            app.electrondensityEditField.Limits = [0 Inf];
            app.electrondensityEditField.ValueChangedFcn = createCallbackFcn(app, @electrondensityEditFieldValueChanged, true);
            app.electrondensityEditField.Value = 8.47e+28;
            app.effectivemassEditFieldLabel = uilabel(app.DispersionrelationTab);
            app.effectivemassEditFieldLabel.Text = 'effective mass';
            app.effectivemassEditField = uieditfield(app.DispersionrelationTab, 'numeric');
            app.effectivemassEditField.Limits = [1e-07 Inf];
            app.effectivemassEditField.ValueChangedFcn = createCallbackFcn(app, @effectivemassEditFieldValueChanged, true);
            app.effectivemassEditField.Value = 1;
            app.UIAxes = uiaxes(app.DispersionrelationTab);
            title(app.UIAxes, 'Dispersion relation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ElectroniccontributiontopermittivittyTab = uitab(app.TabGroup);
            app.ElectroniccontributiontopermittivittyTab.Title = 'Electronic contribution to permittivitty';
            app.UIAxes2_1 = uiaxes(app.ElectroniccontributiontopermittivittyTab);
            title(app.UIAxes2_1, 'Electronic contribution to permittivitty')
            xlabel(app.UIAxes2_1, 'X')
            ylabel(app.UIAxes2_1, 'Y')
            app.resistivityEditFieldLabel = uilabel(app.ElectroniccontributiontopermittivittyTab);
            app.resistivityEditFieldLabel.Text = 'resistivity';
            app.resistivityEditField_2 = uieditfield(app.ElectroniccontributiontopermittivittyTab, 'numeric');
            app.resistivityEditField_2.ValueChangedFcn = createCallbackFcn(app, @resistivityEditField_2ValueChanged, true);
            app.resistivityEditField_2.Value = 1.72e-08;
            app.epsilon_r1Label_2 = uilabel(app.ElectroniccontributiontopermittivittyTab);
            app.epsilon_r1Label_2.Text = 'epsilon_r1';
            app.epsilon1EditField_2 = uieditfield(app.ElectroniccontributiontopermittivittyTab, 'numeric');
            app.epsilon1EditField_2.ValueChangedFcn = createCallbackFcn(app, @epsilon1EditField_2ValueChanged, true);
            app.epsilon1EditField_2.Value = 1;
            app.electrondensityEditField_2Label = uilabel(app.ElectroniccontributiontopermittivittyTab);
            app.electrondensityEditField_2Label.Text = 'electron density';
            app.electrondensityEditField_2 = uieditfield(app.ElectroniccontributiontopermittivittyTab, 'numeric');
            app.electrondensityEditField_2.Limits = [0 Inf];
            app.electrondensityEditField_2.ValueChangedFcn = createCallbackFcn(app, @electrondensityEditField_2ValueChanged, true);
            app.electrondensityEditField_2.Value = 8.47e+28;
            app.effectivemassEditField_2Label = uilabel(app.ElectroniccontributiontopermittivittyTab);
            app.effectivemassEditField_2Label.Text = 'effective mass';
            app.effectivemassEditField_2 = uieditfield(app.ElectroniccontributiontopermittivittyTab, 'numeric');
            app.effectivemassEditField_2.Limits = [1e-07 Inf];
            app.effectivemassEditField_2.ValueChangedFcn = createCallbackFcn(app, @effectivemassEditField_2ValueChanged, true);
            app.effectivemassEditField_2.Value = 1;
            app.UIAxes2_2 = uiaxes(app.ElectroniccontributiontopermittivittyTab);
            title(app.UIAxes2_2, 'Electronic contribution to permittivitty')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.DipolarcontributiontopermittivityTab = uitab(app.TabGroup);
            app.DipolarcontributiontopermittivityTab.Title = 'Dipolar contribution to permittivity';
            app.epsilon_r1Label_3 = uilabel(app.DipolarcontributiontopermittivityTab);
            app.epsilon_r1Label_3.Text = 'epsilon_r1';
            app.epsilon1EditField_3 = uieditfield(app.DipolarcontributiontopermittivityTab, 'numeric');
            app.epsilon1EditField_3.ValueChangedFcn = createCallbackFcn(app, @epsilon1EditField_3ValueChanged, true);
            app.epsilon1EditField_3.Value = 1;
            app.electrondensityEditField_3Label = uilabel(app.DipolarcontributiontopermittivityTab);
            app.electrondensityEditField_3Label.Text = 'electron density';
            app.electrondensityEditField_3 = uieditfield(app.DipolarcontributiontopermittivityTab, 'numeric');
            app.electrondensityEditField_3.Limits = [0 Inf];
            app.electrondensityEditField_3.ValueChangedFcn = createCallbackFcn(app, @electrondensityEditField_3ValueChanged, true);
            app.electrondensityEditField_3.Value = 8.47e+28;
            app.resistivityEditField_2Label = uilabel(app.DipolarcontributiontopermittivityTab);
            app.resistivityEditField_2Label.Text = 'resistivity';
            app.resistivityEditField_3 = uieditfield(app.DipolarcontributiontopermittivityTab, 'numeric');
            app.resistivityEditField_3.ValueChangedFcn = createCallbackFcn(app, @resistivityEditField_3ValueChanged, true);
            app.resistivityEditField_3.Value = 1.72e-08;
            app.effectivemassEditField_3Label = uilabel(app.DipolarcontributiontopermittivityTab);
            app.effectivemassEditField_3Label.Text = 'effective mass';
            app.effectivemassEditField_3 = uieditfield(app.DipolarcontributiontopermittivityTab, 'numeric');
            app.effectivemassEditField_3.Limits = [1e-07 Inf];
            app.effectivemassEditField_3.ValueChangedFcn = createCallbackFcn(app, @effectivemassEditField_3ValueChanged, true);
            app.effectivemassEditField_3.Value = 1;
            app.UIAxes3_1 = uiaxes(app.DipolarcontributiontopermittivityTab);
            title(app.UIAxes3_1, 'Dipolar contribution to permittivitty')
            xlabel(app.UIAxes3_1, 'X')
            ylabel(app.UIAxes3_1, 'Y')
            app.UIAxes3_2 = uiaxes(app.DipolarcontributiontopermittivityTab);
            title(app.UIAxes3_2, 'Dipolar contribution to permittivitty')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plasma_frequency_and_permittivity_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end