classdef plasma_frequency_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        effectivemassEditFieldLabel    matlab.ui.control.Label
        effectivemassEditField         matlab.ui.control.NumericEditField
        electrondensityEditFieldLabel  matlab.ui.control.Label
        electrondensityEditField       matlab.ui.control.NumericEditField
        epsilon_r1Label                matlab.ui.control.Label
        epsilon1EditField              matlab.ui.control.NumericEditField
        resistivityEditFieldLabel      matlab.ui.control.Label
        resistivityEditField           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Tab2                           matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        epsilon_r1Label_2              matlab.ui.control.Label
        epsilon1EditField_2            matlab.ui.control.NumericEditField
        electrondensityEditField_2Label  matlab.ui.control.Label
        electrondensityEditField_2     matlab.ui.control.NumericEditField
        effectivemassEditField_2Label  matlab.ui.control.Label
        effectivemassEditField_2       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        n_e;
        c;
        q;
        m_e
        epsilon_0;
        epsilon_r1;
        epsilon_r2;
        m_0;
        rho;
    end
    
    methods (Access = private)
        
        function [] = new_plot_tab1(app)
            cla(app.UIAxes);
            
            omega_p = sqrt((app.n_e*app.q^2)/(app.epsilon_0*app.epsilon_r1*app.m_0*app.m_e)); %plasma frequency (ionosphere)
            omega_sp = omega_p/sqrt(app.epsilon_r1+1);
            omega = linspace(0, omega_p, 50);
            app.epsilon_r2 = 1 - omega_p^2./omega.^2; %permittivy material 2 (metal) this is a function of frequency
            beta = omega/app.c.*sqrt((app.epsilon_r1*app.epsilon_r2)./(app.epsilon_r1+app.epsilon_r2));
            index = find(omega > omega_sp, 1); %find index of omega > omega_sp
            plot (app.UIAxes, real(beta(1:index-1)), omega(1:index-1)); %plot omega < omega_sp
            text (app.UIAxes, 0, omega(index-1), "$\omega_{sp}$", 'Interpreter', 'latex', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize',14);
            hold (app.UIAxes, "on");
            plot (app.UIAxes, [0 max(real(beta))],[omega_sp omega_sp]); %line at omega_sp
            hold (app.UIAxes, "on");
            plot (app.UIAxes, real(beta(index:end)), omega(index:end)); %plot omega_sp < omega < omega_p
            hold (app.UIAxes, "on");
            beta = linspace (0, max(real(beta))); %redefining beta for omega > omega_p
            omega_plasma = sqrt(omega_p^2 + app.c^2*beta.^2); % plot omega > omega_p
            
            plot (app.UIAxes, real(beta), omega_plasma); % plot omega > omega_p
            text (app.UIAxes, 0, omega_p, "$\omega_p$", 'Interpreter', 'latex', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right','FontSize',14);
            hold (app.UIAxes, "on");
            plot (app.UIAxes, real(beta), app.c*real(beta));
            text (app.UIAxes, max(real(beta)), app.c*max(real(beta)), '$\leftarrow \omega = c \cdot \beta$', 'Interpreter', 'latex','FontSize',14);            
            legend (app.UIAxes, "$\beta = \frac{\omega}{c} \sqrt{\frac{\epsilon_{r1} \epsilon_{r2}}{\epsilon_{r1}+\epsilon_{r2}}}$", "$\omega = \omega_{sp}$", "Prohibited region", "$\omega^2 = \omega_p^2 + c^2 \cdot \beta^2$", "$\omega = c \cdot \beta$",'Interpreter', 'latex', 'Location', 'eastoutside', 'FontSize', 14);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % costants
            app.c = 3*10^8; %light velocity
            app.q = 1.602176634*10^(-19); %electric charge
            app.m_0 = 9.1093837015*10^(-31); %electron mass
            app.epsilon_0 = 8.8541878128*10^(-12); % vacuum permittivity
            
            % parameters
            %app.n_e = 9.65*10^9; %silicon electron density
            %app.n_e = 9.65*10^9; %silicon electron density
            %app.n_e = 5.86*10^28; %conductor electron density (silver)
            %app.n_e = 5.90*10^28; %conductor electron density (gold)
            app.n_e = 8.47*10^28; %conductor electron density (copper)
            app.electrondensityEditField.Value = app.n_e;
            app.electrondensityEditField_2.Value = app.n_e;
            
            app.epsilon_r1 = 1; %permittivy material 1 (dielectric), vacuum permittivy
            app.epsilon1EditField.Value = app.epsilon_r1;
            app.epsilon1EditField_2.Value = app.epsilon_r1;
            
            app.m_e = 1;
            app.effectivemassEditField.Value = app.m_e;
            app.effectivemassEditField_2.Value = app.m_e;
            
            app.rho = 1.72*10^(-8);
            app.resistivityEditField.Value = app.rho;
            
            new_plot_tab1(app);
            
            xlabel (app.UIAxes, "$\beta~\left[\frac{1}{m}\right]$", 'Interpreter', 'latex', 'FontSize', 14);
            ylabel (app.UIAxes, "$\omega~\left[\frac{rad}{s}\right]$", 'Interpreter', 'latex', 'FontSize', 14);
            title (app.UIAxes, "$\omega(\beta)$", 'Interpreter','latex');
        end

        % Callback function
        function epsilon1EditFieldValueChanged(app, event)
            app.epsilon_r1 = app.epsilon1EditField.Value;
            
            new_plot(app);
        end

        % Callback function
        function electrondensityEditFieldValueChanged(app, event)
            app.n_e = app.electrondensityEditField.Value;
           
            new_plot_tab1(app);
        end

        % Callback function
        function effectivemassEditFieldValueChanged(app, event)
            app.m_e = app.effectivemassEditField.Value;
            
            new_plot_tab1(app);
        end

        % Callback function
        function resistivityEditFieldValueChanged(app, event)
            app.rho = app.resistivityEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.epsilon_r1Label = uilabel(app.Tab);
            app.epsilon_r1Label.Text = 'epsilon_r1';
            app.epsilon1EditField = uieditfield(app.Tab, 'numeric');
            app.epsilon1EditField.Value = 1;
            app.electrondensityEditFieldLabel = uilabel(app.Tab);
            app.electrondensityEditFieldLabel.Text = 'electron density';
            app.electrondensityEditField = uieditfield(app.Tab, 'numeric');
            app.electrondensityEditField.Limits = [0 Inf];
            app.electrondensityEditField.Value = 8.47e+28;
            app.effectivemassEditFieldLabel = uilabel(app.Tab);
            app.effectivemassEditFieldLabel.Text = 'effective mass';
            app.effectivemassEditField = uieditfield(app.Tab, 'numeric');
            app.effectivemassEditField.Limits = [1e-07 Inf];
            app.effectivemassEditField.Value = 1;
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.resistivityEditFieldLabel = uilabel(app.Tab2);
            app.resistivityEditFieldLabel.Text = 'resistivity';
            app.resistivityEditField = uieditfield(app.Tab2, 'numeric');
            app.resistivityEditField.Value = 1.72e-08;
            app.epsilon_r1Label_2 = uilabel(app.Tab2);
            app.epsilon_r1Label_2.Text = 'epsilon_r1';
            app.epsilon1EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.epsilon1EditField_2.Value = 1;
            app.electrondensityEditField_2Label = uilabel(app.Tab2);
            app.electrondensityEditField_2Label.Text = 'electron density';
            app.electrondensityEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.electrondensityEditField_2.Limits = [0 Inf];
            app.electrondensityEditField_2.Value = 8.47e+28;
            app.effectivemassEditField_2Label = uilabel(app.Tab2);
            app.effectivemassEditField_2Label.Text = 'effective mass';
            app.effectivemassEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.effectivemassEditField_2.Limits = [1e-07 Inf];
            app.effectivemassEditField_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plasma_frequency_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end